classdef Project_AudioControlInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadButton                     matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        CurrentFileName                matlab.ui.control.Label
        StartLabel                     matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        PlaybackRateValue              matlab.ui.control.NumericEditField
        PlaybackRateEditFieldLabel     matlab.ui.control.Label
        VolumeButton                   matlab.ui.control.Button
        VolumeSlider                   matlab.ui.control.Slider
        VolumeLabel                    matlab.ui.control.Label
        VolumeLabel2                   matlab.ui.control.Label
        EndField                       matlab.ui.control.EditField
        EndEditFieldLabel              matlab.ui.control.Label
        StartField                     matlab.ui.control.EditField
        StartEditFieldLabel            matlab.ui.control.Label
        SegmentSlider                  matlab.ui.control.RangeSlider
        StopButton                     matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        PlayButton                     matlab.ui.control.Button
        ClickMeToSeeWhatHappensButton  matlab.ui.control.Button
        TheEffectedCornerLabel         matlab.ui.control.Label
        EffectsDropDown                matlab.ui.control.DropDown
        Image1                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        AudioAxes                      matlab.ui.control.UIAxes
        SpectrogramAxes                matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        audioData       double
        Fs              double
        OG_audioData    double
        OG_Fs           double
        startIndex      double
        endIndex        double
        volume          int8
        Player          audioplayer
        IsPaused        logical = false;
        PlaybackLine    matlab.graphics.chart.primitive.Line
    end
    
    methods (Access = private)
        
        function DisplaySpectrogram(app)
            segment = app.audioData(app.startIndex:app.endIndex);
            [s, f, t, ps] = spectrogram(segment, 256, 250, 256, app.Fs, 'yaxis');
            imagesc(app.SpectrogramAxes, t, f, 10*log10(abs(ps)));
            axis(app.SpectrogramAxes, 'xy');
            colormap(app.SpectrogramAxes, 'jet');
            colorbar(app.SpectrogramAxes);
            title(app.SpectrogramAxes, 'Spectrogram');
            xlabel(app.SpectrogramAxes, 'Time (s)');
            ylabel(app.SpectrogramAxes, 'Frequency (Hz)');
        end

        function PlotPlaybackLine(app)
            cla(app.AudioAxes);
            hold(app.AudioAxes, 'on');
            plot(app.AudioAxes, (app.startIndex:app.endIndex)/app.Fs, app.audioData(app.startIndex:app.endIndex));
            plot(app.AudioAxes, (1:app.startIndex)/app.Fs, app.audioData(1:app.startIndex), 'Color', [0.5 0.5 0.5]);
            plot(app.AudioAxes, (app.endIndex:length(app.audioData))/app.Fs, app.audioData(app.endIndex:length(app.audioData)), 'Color', [0.5 0.5 0.5]);
            hold(app.AudioAxes, 'off');
            t = (app.startIndex:app.endIndex)/app.Fs;
            x = [app.startIndex, app.endIndex] / app.Fs; % Convert to time
            y = ylim(app.AudioAxes);
            if isfield(app, 'PlaybackLine') && isvalid(app.PlaybackLine)
                delete(app.PlaybackLine);
            end
            hold(app.AudioAxes, 'on');
            app.PlaybackLine = plot(app.AudioAxes, [x(1), x(1)], y, 'r', 'LineWidth', 2); 
            hold(app.AudioAxes, 'off');
            drawnow;
            
            % Update the position of the line during playback
            while isplaying(app.Player)
                currentPosition = (app.startIndex+app.Player.CurrentSample) / app.Player.SampleRate;
                set(app.PlaybackLine, 'XData', [currentPosition, currentPosition]); % Update x-coordinate
                drawnow;
                set([app.SegmentSlider, app.StartField, app.EndField, app.ResetButton, app.PlaybackRateValue], 'Enable', 'off');
            end
            set([app.SegmentSlider, app.StartField, app.EndField, app.ResetButton, app.PlaybackRateValue], 'Enable', 'on');
        end

        function Update(app)
            duration = length(app.audioData) / app.Fs;
            app.AudioAxes.XLim = [0 duration];
            app.SegmentSlider.Limits = [0 duration];
            app.SegmentSlider.Value(1) = 0;
            app.SegmentSlider.Value(2) = duration;
            app.StartField.Value = num2str(0);
            app.EndField.Value = num2str(duration);
            app.PlaybackRateValue.Value = app.Fs;
        end

        function addEcho(app, audio_data, Fs, delay, decay)
            initial_echo = [1; zeros(round(delay*Fs), 1); decay];

            % Ensure both inputs are column vectors
            audio_data = audio_data(:);
            initial_echo = initial_echo(:);

            echoed_audio = conv(audio_data, initial_echo);
            reverb_strength = 0.1;
            reverb = reverberator('PreDelay', round(delay*Fs), 'WetDryMix', reverb_strength);
            echoed_audio = reverb(echoed_audio);
            echoed_audio = echoed_audio / max(abs(echoed_audio));
            app.audioData = echoed_audio;
            app.startIndex = 1;
            app.endIndex = length(app.audioData)/2;
        end

        function addCanyonEcho(app, audio_data, Fs, delay, decay)
            initial_echo = [1; zeros(round(delay * Fs / 2), 1); decay * 0.5]; 
        
            % Ensure both inputs are column vectors
            audio_data = audio_data(:);
            initial_echo = initial_echo(:);

            echoed_audio = conv(audio_data, initial_echo);
            reverb = reverberator('PreDelay',  round(delay * Fs / 2), ...
                                  'WetDryMix', 0.4, ...
                                  'HighCutFrequency', 4000, ... 
                                  'Diffusion', 0.7, ...
                                  'DecayFactor', decay * 0.5);
            echoed_audio = reverb(echoed_audio);
            echoed_audio = echoed_audio / max(abs(echoed_audio));
            app.audioData = echoed_audio;
            app.startIndex = 1;
            app.endIndex = length(app.audioData)/2;
            
        end

        function addStorm(app, audio_data, Fs)
            [storm, Fs_storm] = audioread('Effects/Storm_v2.wav');
            storm_resampled = resample(storm, Fs, Fs_storm);

            storm_resampled = storm_resampled / max(abs(storm_resampled));
            storm_resampled = (double(app.VolumeSlider.Value) + 10) / 100 * storm_resampled;

            % Ensure both inputs are column vectors
            audio_data = audio_data(:);
            storm_resampled = storm_resampled(:);
            
            % Ensure both audio_data and storm_resampled have the same length
            min_length = min(length(audio_data), length(storm_resampled));
            audio_data = audio_data(1:min_length);
            storm_resampled = storm_resampled(1:min_length);
            
            changed_audio = audio_data + storm_resampled;
            changed_audio = changed_audio / max(abs(changed_audio));
            app.audioData = changed_audio;
            app.startIndex = 1;
            app.endIndex = length(app.audioData);
        end

        function addForest(app, audio_data, Fs)
            [forest, Fs_forest] = audioread('Effects/AMBForst_Forest.wav');
            forest_resampled = resample(forest, Fs, Fs_forest);

            forest_resampled = forest_resampled / max(abs(forest_resampled));
            forest_resampled = (double(app.VolumeSlider.Value)) / 100 * forest_resampled; 

            % Ensure both inputs are column vectors
            audio_data = audio_data(:);
            forest_resampled = forest_resampled(:);
            
            % Ensure both audio_data and storm_resampled have the same length
            min_length = min(length(audio_data), length(forest_resampled));
            audio_data = audio_data(1:min_length);
            forest_resampled = forest_resampled(1:min_length);
            
            changed_audio = audio_data + forest_resampled;
            changed_audio = changed_audio / max(abs(changed_audio));
            app.audioData = changed_audio;
            app.startIndex = 1;
            app.endIndex = length(app.audioData);
        end

        function addChicken(app, audio_data, Fs)
            [henhouse, Fs_henhouse] = audioread('Effects/Henhouse.wav');
            henhouse_resampled = resample(henhouse, Fs, Fs_henhouse);

            henhouse_resampled = henhouse_resampled / max(abs(henhouse_resampled));
            henhouse_resampled = (double(app.VolumeSlider.Value)) / 100 * henhouse_resampled;

            % Ensure both inputs are column vectors
            audio_data = audio_data(:);
            henhouse_resampled = henhouse_resampled(:);
            
            % Ensure both audio_data and storm_resampled have the same length
            min_length = min(length(audio_data), length(henhouse_resampled));
            audio_data = audio_data(1:min_length);
            henhouse_resampled = henhouse_resampled(1:min_length);
            
            changed_audio = audio_data + henhouse_resampled;
            changed_audio = changed_audio / max(abs(changed_audio));
            app.audioData = changed_audio;
            app.startIndex = 1;
            app.endIndex = length(app.audioData);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file, path] = uigetfile({'*.wav;*.mp3;*.m4a;*.flac', 'Audio Files (*.wav, *.mp3, *.m4a, *.flac)'; 
                              '*.wav', 'WAV Files (*.wav)'; 
                              '*.mp3', 'MP3 Files (*.mp3)';
                              '*.m4a', 'M4A Files (*.m4a)';
                              '*.flac', 'FLAC Files (*.flac)'; 
                              '*.*', 'All Files (*.*)'});
            if isequal(file, 0)
                figure(app.UIFigure);
                return; % User canceled file selection
            end

            [app.audioData, app.Fs] = audioread(fullfile(path, file));
            app.OG_audioData = app.audioData;
            app.OG_Fs = app.Fs;
            plot(app.AudioAxes, (1:length(app.audioData))/app.Fs, app.audioData);
            title(app.AudioAxes, 'Audio Waveform');
            app.startIndex = max(round(app.SegmentSlider.Value(1) * app.Fs), 1);
            app.endIndex = min(round(app.SegmentSlider.Value(2) * app.Fs), length(app.audioData));
            app.Update;
            set(findall(app.UIFigure, '-property', 'Enable'), 'Enable', 'on');
            set([app.PauseButton, app.StopButton, app.PlaybackRateValue], 'Enable', 'off');
            app.StartLabel.Text = "";
            app.CurrentFileName.Text = ["Current File : ", file];
            app.DisplaySpectrogram; 
            figure(app.UIFigure);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile({'*.wav;*.mp3;*.m4a;*.flac', 'Audio Files (*.wav, *.mp3, *.m4a, *.flac)'; 
                              '*.wav', 'WAV Files (*.wav)'; 
                              '*.mp3', 'MP3 Files (*.mp3)';
                              '*.m4a', 'M4A Files (*.m4a)';
                              '*.flac', 'FLAC Files (*.flac)'; 
                              '*.*', 'All Files (*.*)'}, 'Save Audio');
            if isequal(file, 0)
                figure(app.UIFigure);
                return; % User canceled file selection
            end

            fullFileName = fullfile(path, file);
            audiowrite(fullFileName, app.audioData, app.Fs);
            figure(app.UIFigure);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            segment = app.audioData(app.startIndex:app.endIndex);
            app.IsPaused = false;
            app.Player = audioplayer(segment, app.Fs);
            play(app.Player, [app.startIndex, app.endIndex]);
            set([app.PauseButton, app.StopButton, app.PlaybackRateValue], 'Enable', 'on');
            app.PlotPlaybackLine; 
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.IsPaused
                resume(app.Player);
                app.IsPaused = false; 
                app.PlotPlaybackLine;
            else
                pause(app.Player);
                app.IsPaused = true;
            end            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.Player);
            delete(app.PlaybackLine);
            app.IsPaused = true;
            cla(app.AudioAxes);
            plot(app.AudioAxes, (1:length(app.audioData))/app.Fs, app.audioData);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.audioData = app.OG_audioData;
            app.Fs = app.OG_Fs;
            app.Update;
            app.startIndex = max(round(app.SegmentSlider.Value(1) * app.Fs), 1);
            app.endIndex = min(round(app.SegmentSlider.Value(2) * app.Fs), length(app.audioData));
            cla(app.AudioAxes);
            plot(app.AudioAxes, (app.startIndex:app.endIndex)/app.Fs, app.audioData(app.startIndex:app.endIndex));
        end

        % Value changing function: SegmentSlider
        function SegmentSliderValueChanging(app, event)
            range = event.Value;
            app.StartField.Value = num2str(range(1));
            app.EndField.Value = num2str(range(2));
            app.startIndex = max(round(range(1) * app.Fs), 1);
            app.endIndex = min(round(range(2) * app.Fs), length(app.audioData));

            cla(app.AudioAxes);
            hold(app.AudioAxes, 'on');
            plot(app.AudioAxes, (app.startIndex:app.endIndex)/app.Fs, app.audioData(app.startIndex:app.endIndex));
            plot(app.AudioAxes, (1:app.startIndex)/app.Fs, app.audioData(1:app.startIndex), 'Color', [0.5 0.5 0.5]);
            plot(app.AudioAxes, (app.endIndex:length(app.audioData))/app.Fs, app.audioData(app.endIndex:length(app.audioData)), 'Color', [0.5 0.5 0.5]);
            plot(app.AudioAxes, [range(1) range(1)], ylim(app.AudioAxes), 'k');
            plot(app.AudioAxes, [range(2) range(2)], ylim(app.AudioAxes), 'k');
            hold(app.AudioAxes, 'off');
        end

        % Value changed function: EndField, StartField
        function EndStartValueChanged(app, event)
            if str2double(app.StartField.Value) >= 0 && str2double(app.StartField.Value) <= app.SegmentSlider.Limits(2) && str2double(app.EndField.Value) >= 0 && str2double(app.EndField.Value) <= app.SegmentSlider.Limits(2) && str2double(app.StartField.Value) <= str2double(app.EndField.Value)
                start_value = str2double(app.StartField.Value);
                end_value = str2double(app.EndField.Value);
                app.SegmentSlider.Value = [start_value, end_value];
                app.startIndex = max(round(app.SegmentSlider.Value(1) * app.Fs), 1);
                app.endIndex = min(round(app.SegmentSlider.Value(2) * app.Fs), length(app.audioData));
    
                cla(app.AudioAxes);
                hold(app.AudioAxes, 'on');
                plot(app.AudioAxes, (app.startIndex:app.endIndex)/app.Fs, app.audioData(app.startIndex:app.endIndex));
                plot(app.AudioAxes, (1:app.startIndex)/app.Fs, app.audioData(1:app.startIndex), 'Color', [0.5 0.5 0.5]);
                plot(app.AudioAxes, (app.endIndex:length(app.audioData))/app.Fs, app.audioData(app.endIndex:length(app.audioData)), 'Color', [0.5 0.5 0.5]);
                plot(app.AudioAxes, [app.startIndex app.startIndex], ylim(app.AudioAxes), 'k');
                plot(app.AudioAxes, [app.endIndex app.endIndex], ylim(app.AudioAxes), 'k');
                hold(app.AudioAxes, 'off');
            else
                app.StartField.Value = num2str(app.SegmentSlider.Value(1));
                app.EndField.Value = num2str(app.SegmentSlider.Value(2));
            end            
        end

        % Value changed function: PlaybackRateValue
        function PlaybackRateValueValueChanged(app, event)
            if (app.PlaybackRateValue.Value >= 80 && app.PlaybackRateValue.Value <= 1000000)
                app.Fs = app.PlaybackRateValue.Value;
                if isplaying(app.Player)
                    stop(app.Player);
                    app.IsPaused = true;
                end
                segment = app.audioData(app.startIndex:app.endIndex);
                app.Player = audioplayer(segment, app.Fs);
                app.Update;
                app.PlotPlaybackLine;
            else
                app.PlaybackRateValue.Value = app.Fs;
            end
        end

        % Value changing function: VolumeSlider
        function VolumeSliderValueChanging(app, event)
            app.volume = event.Value;
            app.VolumeLabel2.Text = num2str(app.volume);
            app.audioData = app.audioData / max(abs(app.audioData));
            app.audioData = app.audioData * (double(app.volume) / 100);
            segment = app.audioData(app.startIndex:app.endIndex);
            app.Player = audioplayer(segment, app.Fs);
            app.Update;
        end

        % Button pushed function: VolumeButton
        function VolumeButtonPushed(app, event)
            if app.VolumeSlider.Visible == 0
                set([app.VolumeSlider, app.VolumeLabel, app.VolumeLabel2], 'Visible', 'on');
            else
                set([app.VolumeSlider, app.VolumeLabel, app.VolumeLabel2], 'Visible', 'off');
            end
        end

        % Button pushed function: ClickMeToSeeWhatHappensButton
        function ClickMeToSeeWhatHappensButtonPushed(app, event)
            set([app.TheEffectedCornerLabel, app.EffectsDropDown, app.Image2, app.Image1, app.Image4], 'Visible', 'on');
            set([app.ClickMeToSeeWhatHappensButton, app.Image3], 'Visible', 'off');
        end

        % Value changed function: EffectsDropDown
        function EffectsDropDownValueChanged(app, event)
            effect = app.EffectsDropDown.Value;
            switch effect
                case 'Add Echo'
                    app.addEcho(app.audioData, app.Fs, 0.5, 0.2);
                case 'Add Canyon Echo'
                    app.addCanyonEcho(app.audioData, app.Fs, 0.5, 0.2);
                case 'Add Storm'
                    app.addStorm(app.audioData, app.Fs);
                case 'Add Forest'
                    app.addForest(app.audioData, app.Fs);
                case 'Add Surprise'
                    app.addChicken(app.audioData, app.Fs);
            end           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpectrogramAxes = uiaxes(app.UIFigure);
            zlabel(app.SpectrogramAxes, 'Z')
            app.AudioAxes = uiaxes(app.UIFigure);
            zlabel(app.AudioAxes, 'Z')
            app.AudioAxes.YLim = [-1 1];
            app.AudioAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.AudioAxes.XTickLabel = '';
            app.AudioAxes.YTick = [-1 0 1];
            app.AudioAxes.YTickLabel = {'-1'; '0'; '1'};
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.Visible = 'off';
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Symbols', '360_F_536825525_57N1qviyG9FOlPMC7w8yNca0HJfBM55g2-removebg-preview.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Symbols', '360_F_409066324_e6hmzIJlAyvTTT9LYIG17Xtk19VOkJ0F-removebg-preview.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Symbols', 'Untitled2-removebg-preview.png');
            app.Image1 = uiimage(app.UIFigure);
            app.Image1.Visible = 'off';
            app.Image1.ImageSource = fullfile(pathToMLAPP, 'Symbols', 'Untitled-removebg-preview.png');
            app.EffectsDropDown = uidropdown(app.UIFigure);
            app.EffectsDropDown.Items = {'Choose Effect', 'Add Echo', 'Add Canyon Echo', 'Add Storm', 'Add Forest', 'Add Surprise'};
            app.EffectsDropDown.ValueChangedFcn = createCallbackFcn(app, @EffectsDropDownValueChanged, true);
            app.EffectsDropDown.Enable = 'off';
            app.EffectsDropDown.Visible = 'off';
            app.EffectsDropDown.Value = 'Choose Effect';
            app.TheEffectedCornerLabel = uilabel(app.UIFigure);
            app.TheEffectedCornerLabel.Visible = 'off';
            app.TheEffectedCornerLabel.Text = 'The Effected Corner';
            app.ClickMeToSeeWhatHappensButton = uibutton(app.UIFigure, 'push');
            app.ClickMeToSeeWhatHappensButton.ButtonPushedFcn = createCallbackFcn(app, @ClickMeToSeeWhatHappensButtonPushed, true);
            app.ClickMeToSeeWhatHappensButton.Enable = 'off';
            app.ClickMeToSeeWhatHappensButton.Text = 'Click Me To See What Happens';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'Symbols', '450px-Octicons-playback-play.svg.png');
            app.PlayButton.Enable = 'off';
            app.PlayButton.Tooltip = {'Play'};
            app.PlayButton.Text = '';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = fullfile(pathToMLAPP, 'Symbols', '450px-Play_Pause_icon_2283501.svg.png');
            app.PauseButton.Enable = 'off';
            app.PauseButton.Tooltip = {'Play / Pause'};
            app.PauseButton.Text = '';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = fullfile(pathToMLAPP, 'Symbols', 'Octicons-primitive-square.svg.png');
            app.StopButton.Enable = 'off';
            app.StopButton.Tooltip = {'Stop'};
            app.StopButton.Text = '';
            app.SegmentSlider = uislider(app.UIFigure, 'range');
            app.SegmentSlider.ValueChangingFcn = createCallbackFcn(app, @SegmentSliderValueChanging, true);
            app.SegmentSlider.Enable = 'off';
            app.StartEditFieldLabel = uilabel(app.UIFigure);
            app.StartEditFieldLabel.Enable = 'off';
            app.StartEditFieldLabel.Text = 'Start';
            app.StartField = uieditfield(app.UIFigure, 'text');
            app.StartField.ValueChangedFcn = createCallbackFcn(app, @EndStartValueChanged, true);
            app.StartField.Enable = 'off';
            app.EndEditFieldLabel = uilabel(app.UIFigure);
            app.EndEditFieldLabel.Enable = 'off';
            app.EndEditFieldLabel.Text = 'End';
            app.EndField = uieditfield(app.UIFigure, 'text');
            app.EndField.ValueChangedFcn = createCallbackFcn(app, @EndStartValueChanged, true);
            app.EndField.Enable = 'off';
            app.VolumeLabel2 = uilabel(app.UIFigure);
            app.VolumeLabel2.Enable = 'off';
            app.VolumeLabel2.Visible = 'off';
            app.VolumeLabel2.Text = '50';
            app.VolumeLabel = uilabel(app.UIFigure);
            app.VolumeLabel.Enable = 'off';
            app.VolumeLabel.Visible = 'off';
            app.VolumeLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.UIFigure);
            app.VolumeSlider.MajorTicks = [0 20 40 60 80 100];
            app.VolumeSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.VolumeSlider.ValueChangingFcn = createCallbackFcn(app, @VolumeSliderValueChanging, true);
            app.VolumeSlider.MinorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.VolumeSlider.Visible = 'off';
            app.VolumeSlider.Value = 50;
            app.VolumeButton = uibutton(app.UIFigure, 'push');
            app.VolumeButton.ButtonPushedFcn = createCallbackFcn(app, @VolumeButtonPushed, true);
            app.VolumeButton.Icon = fullfile(pathToMLAPP, 'Symbols', 'medium-volume.png');
            app.VolumeButton.Enable = 'off';
            app.VolumeButton.Tooltip = {'Open / Close Volume Control'};
            app.VolumeButton.Text = '';
            app.PlaybackRateEditFieldLabel = uilabel(app.UIFigure);
            app.PlaybackRateEditFieldLabel.Enable = 'off';
            app.PlaybackRateEditFieldLabel.Text = 'Playback Rate';
            app.PlaybackRateValue = uieditfield(app.UIFigure, 'numeric');
            app.PlaybackRateValue.ValueDisplayFormat = '%.0f';
            app.PlaybackRateValue.AllowEmpty = 'on';
            app.PlaybackRateValue.ValueChangedFcn = createCallbackFcn(app, @PlaybackRateValueValueChanged, true);
            app.PlaybackRateValue.Enable = 'off';
            app.PlaybackRateValue.Value = [];
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Tooltip = {'Reset All Changes'};
            app.ResetButton.Text = 'Reset';
            app.StartLabel = uilabel(app.UIFigure);
            app.StartLabel.Text = 'Please Load an audio file to start';
            app.CurrentFileName = uilabel(app.UIFigure);
            app.CurrentFileName.Text = '';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'Symbols', '3974682-200.png');
            app.SaveButton.Enable = 'off';
            app.SaveButton.Tooltip = {'Save File'};
            app.SaveButton.Text = '';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = fullfile(pathToMLAPP, 'Symbols', 'folder.png');
            app.LoadButton.Tooltip = {'Load File'};
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_AudioControlInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end